classdef AmongAx < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        loadallaxonsButton            matlab.ui.control.Button
        loadingState                  matlab.ui.control.TextArea
        PagetotalNumberTextArea       matlab.ui.control.TextArea
        PagetotalNumberTextAreaLabel  matlab.ui.control.Label
        axonStatsTable                matlab.ui.control.Table
        SpottheimposterLabel          matlab.ui.control.Label
        NextpageButton                matlab.ui.control.Button
        LastpageButton                matlab.ui.control.Button
        AxontotalnumberTextArea       matlab.ui.control.TextArea
        AxontotalnumberTextAreaLabel  matlab.ui.control.Label
        MatchedcelltypeDropDown       matlab.ui.control.DropDown
        MatchedcelltypeDropDownLabel  matlab.ui.control.Label
        depth4                        matlab.ui.control.UIAxes
        depth3                        matlab.ui.control.UIAxes
        depth2                        matlab.ui.control.UIAxes
        depth1                        matlab.ui.control.UIAxes
        axon4                         matlab.ui.control.UIAxes
        axon3                         matlab.ui.control.UIAxes
        axon2                         matlab.ui.control.UIAxes
        axon1                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cellTypeCollection = {}; % Description
        selected_types  = null; % Description
        totalAxons = 0; % Description
        pageTotalN=0; % Description
    end
    
    methods (Access = private)
       
        
        function loadAxonOfSelectedType(app)
            %writing the feedback text box
            message = sprintf('Loading axons of cell type: %s', app.selected_types);
            app.loadingState.Value = message;

            %load the axons

            q = sprintf('cell_types = "%s"', app.selected_types);
            cellAxonids = fetch(sln_cell.Cell * sln_cell.CellEvent * sln_cell.AssignType * sln_cell.CellAxonMatch&q);
            app.totalAxons = numel(cellAxonids);

            
            app.AxontotalnumberTextArea.Value = num2str(app.totalAxons);
            %now deciding page number
            if(rem(app.totalAxons,4)==0)
                app.PagetotalN = app.totalAxons/4;
            else
                app.pageTotalN = app.totalAxons/4+1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axoncell = fetch(sln_cell.CellAxonMatch, 'cell_unid');
            cells = unique([axoncell.cell_unid]);
            types = {};
            for i = 1:numel(cells)
                %query for the last assigned type
                q = sprintf('cell_unid = %d', cells(i));
                classifying = fetch(sln_cell.CellEvent * sln_cell.AssignType & q);
                eventids = [classifying.event_id];

                maxid = max(eventids);
                types{end+1} = classifying(find([eventids==maxid])).cell_type;
            end
            %filter out the unique cell types 
            app.cellTypeCollection = unique(types);
        end

        % Drop down opening function: MatchedcelltypeDropDown
        function MatchedcelltypeDropDownOpening(app, event)
            if(~isempty(app.cellTypeCollection))
                app.MatchedcelltypeDropDown.Items = app.cellTypeCollection;
                app.MatchedcelltypeDropDown.Value = app.cellTypeCollection{1};
            end
        end

        % Value changed function: MatchedcelltypeDropDown
        function MatchedcelltypeDropDownValueChanged(app, event)
            app.selected_types = app.MatchedcelltypeDropDown.Value;
            app.loadAxonOfSelectedType;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axon1 = uiaxes(app.UIFigure);
            title(app.axon1, 'Title')
            zlabel(app.axon1, 'Z')
            app.axon2 = uiaxes(app.UIFigure);
            title(app.axon2, 'Title')
            xlabel(app.axon2, 'X')
            ylabel(app.axon2, 'Y')
            zlabel(app.axon2, 'Z')
            app.axon3 = uiaxes(app.UIFigure);
            title(app.axon3, 'Title')
            xlabel(app.axon3, 'X')
            ylabel(app.axon3, 'Y')
            zlabel(app.axon3, 'Z')
            app.axon4 = uiaxes(app.UIFigure);
            title(app.axon4, 'Title')
            xlabel(app.axon4, 'X')
            ylabel(app.axon4, 'Y')
            zlabel(app.axon4, 'Z')
            app.depth1 = uiaxes(app.UIFigure);
            title(app.depth1, 'Title')
            xlabel(app.depth1, 'X')
            ylabel(app.depth1, 'Y')
            zlabel(app.depth1, 'Z')
            app.depth2 = uiaxes(app.UIFigure);
            title(app.depth2, 'Title')
            xlabel(app.depth2, 'X')
            ylabel(app.depth2, 'Y')
            zlabel(app.depth2, 'Z')
            app.depth3 = uiaxes(app.UIFigure);
            title(app.depth3, 'Title')
            xlabel(app.depth3, 'X')
            ylabel(app.depth3, 'Y')
            zlabel(app.depth3, 'Z')
            app.depth4 = uiaxes(app.UIFigure);
            title(app.depth4, 'Title')
            xlabel(app.depth4, 'X')
            ylabel(app.depth4, 'Y')
            zlabel(app.depth4, 'Z')
            app.MatchedcelltypeDropDownLabel = uilabel(app.UIFigure);
            app.MatchedcelltypeDropDownLabel.Text = 'Matched cell type';
            app.MatchedcelltypeDropDown = uidropdown(app.UIFigure);
            app.MatchedcelltypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @MatchedcelltypeDropDownOpening, true);
            app.MatchedcelltypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MatchedcelltypeDropDownValueChanged, true);
            app.AxontotalnumberTextAreaLabel = uilabel(app.UIFigure);
            app.AxontotalnumberTextAreaLabel.Text = 'Axon total number';
            app.AxontotalnumberTextArea = uitextarea(app.UIFigure);
            app.LastpageButton = uibutton(app.UIFigure, 'push');
            app.LastpageButton.Text = 'Last page';
            app.NextpageButton = uibutton(app.UIFigure, 'push');
            app.NextpageButton.Text = 'Next page';
            app.SpottheimposterLabel = uilabel(app.UIFigure);
            app.SpottheimposterLabel.Text = 'Spot the imposter!';
            app.axonStatsTable = uitable(app.UIFigure);
            app.axonStatsTable.ColumnName = {'Axon id'; 'total length'; '2d density'; 'eccentricity'};
            app.axonStatsTable.RowName = {};
            app.PagetotalNumberTextAreaLabel = uilabel(app.UIFigure);
            app.PagetotalNumberTextAreaLabel.Text = 'Page total Number';
            app.PagetotalNumberTextArea = uitextarea(app.UIFigure);
            app.loadingState = uitextarea(app.UIFigure);
            app.loadallaxonsButton = uibutton(app.UIFigure, 'push');
            app.loadallaxonsButton.Text = 'load all axons';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AmongAx

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end