classdef AxonSkewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SelectgButton                  matlab.ui.control.Button
        TotalorthogonalEditField       matlab.ui.control.NumericEditField
        TotalorthogonalEditFieldLabel  matlab.ui.control.Label
        StopselectingButton            matlab.ui.control.Button
        OutputstatusTextArea           matlab.ui.control.TextArea
        OutputstatusTextAreaLabel      matlab.ui.control.Label
        ConfirmoutputButton            matlab.ui.control.Button
        DisplayonskeletonButton        matlab.ui.control.Button
        SaveoutlineButton              matlab.ui.control.Button
        DrawoutlineButton              matlab.ui.control.Button
        DeleteoutlineButton            matlab.ui.control.Button
        ImagepathTextArea              matlab.ui.control.TextArea
        ImagepathTextAreaLabel         matlab.ui.control.Label
        LoadskeletonButton             matlab.ui.control.Button
        LoadrawButton                  matlab.ui.control.Button
        UIAxes1                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        clickedPts  %points clicked when drawing brain border in image 1
        drawnLineHandles %graphic handles for the drawing, lines
        markerHandles %graphic handles for the drawing, markers
        orthoLineHandles %graphic handles for the orthogonal lines in image 2
        markerHandles2
        drawnLineHandles2
        totalOrthL %number of orthogonal lines to draw
        orthoAll %cerry array that stores the slope and intercepts and starting points of the orthogonal lines
        img1Path %file path of the image 1
        isDrawing = false %state, whether the app is drawing
        isSelecting = false %state, whether the app is taking key press g as orthogonal line selection
        selectedIdx
        selectedOrtho = [] %final result
        Region = ''              % 'SCs' or 'dLGN', chosen at startup
        RegionDlg                % handle of the startup region-selection window
        LGNFigure                % the dLGN window (built programmatically)

        % --- dLGN window components (created by buildLGNWindow) ---
        LGNAxes                  % the single image axes in the dLGN window
        LGNPathArea              % image path text area
        LGNStatusArea            % status text area
        LGNBtn = struct()        % all dLGN buttons, keyed by name

        % --- dLGN annotation state ---
        LGNMode = 'idle'         % 'idle'|'drawing'|'lateral'|'medial'|'dmpole'|'vlpole'
        LGNImgPath = ''          % path of the loaded raw image
        LGNLoop = zeros(0,2)     % N-by-2 loop vertex coordinates, in click order
        LGNLoopClosed = false    % true once the loop has been closed
        LGNLatIdx = []           % indices into LGNLoop that form the lateral line
        LGNMedIdx = []           % indices into LGNLoop that form the medial line
        LGNDMPoleIdx = []        % single index into LGNLoop: the dorsal-medial pole
        LGNVLPoleIdx = []        % single index into LGNLoop: the ventral-lateral pole
        LGNVertexH = gobjects(0) % one marker handle per vertex, same order as LGNLoop
        LGNEdgeH = gobjects(0)   % handles of the polyline edges (incl. the closing edge)
 
        % --- appearance / tuning ---
        LGNColUnlabeled = [1 0 0]   % red     - a dot with no label yet
        LGNColLateral   = [0 1 1]   % cyan    - lateral line
        LGNColMedial    = [1 1 0]   % yellow  - medial line
        LGNColDMPole    = [1 0 1]   % magenta - dorsal-medial pole (drawn larger)
        LGNColVLPole    = [0 1 0]   % green   - ventral-lateral pole (drawn larger)
        LGNSnapTolFrac  = 0.02      % click snap tolerance, see lgnLabelNearest
    end

    methods (Access = private)
        function chooseRegion(app)
            % Startup dialog: pick the brain region before the main GUI shows
            % Modal dialog shown before either main window: pick SCs or dLGN.
            app.UIFigure.Visible = 'off';
 
            dlgW = 340; dlgH = 160;
            scr = get(groot, 'ScreenSize');
            pos = [scr(3)/2 - dlgW/2, scr(4)/2 - dlgH/2, dlgW, dlgH];
 
            app.RegionDlg = uifigure('Name', 'Brain region', ...
                'Position', pos, 'Resize', 'off', ...
                'CloseRequestFcn', @(s,e) regionDlgClosed(app));
            try
                app.RegionDlg.WindowStyle = 'modal';   % ignored on older releases
            catch
            end
 
            uilabel(app.RegionDlg, ...
                'Text', 'Choose the brain region you want to work on', ...
                'FontName', 'Lucida Bright', 'FontSize', 13, ...
                'HorizontalAlignment', 'center', 'WordWrap', 'on', ...
                'Position', [10 95 dlgW-20 40]);
 
            uibutton(app.RegionDlg, 'push', 'Text', 'SCs', ...
                'FontName', 'Lucida Bright', 'FontSize', 14, ...
                'Position', [45 35 100 36], ...
                'ButtonPushedFcn', @(s,e) regionChosen(app, 'SCs'));
 
            uibutton(app.RegionDlg, 'push', 'Text', 'dLGN', ...
                'FontName', 'Lucida Bright', 'FontSize', 14, ...
                'Position', [195 35 100 36], ...
                'ButtonPushedFcn', @(s,e) regionChosen(app, 'dLGN'));
 
            uiwait(app.RegionDlg);   % blocks until a button is pressed
        end

        function regionChosen(app, region)
            app.Region = region;
            if ~isempty(app.RegionDlg) && isvalid(app.RegionDlg)
                delete(app.RegionDlg);      % releases uiwait
            end
            app.RegionDlg = [];
 
            switch region
                case 'SCs'
                    showSCsLayout(app);
                case 'dLGN'
                    showLGNLayout(app);
            end
        end

        function regionDlgClosed(app)
            % Chooser closed without a selection -> quit the app.
            delete(app.RegionDlg);
            app.RegionDlg = [];
            delete(app);
        end

        function showSCsLayout(app)
            % Current window / layout, unchanged
            if ~isempty(app.LGNFigure) && isvalid(app.LGNFigure)
                delete(app.LGNFigure);
            end
            app.LGNFigure = [];

            app.UIFigure.Name = 'AxonSkewer - SCs';
            app.UIFigure.Visible = 'on';
            try
                figure(app.UIFigure);   % bring to front
            catch
            end
        end

        function showLGNLayout(app)
            app.UIFigure.Visible = 'off';   % SCs layout stays hidden in dLGN mode
            if isempty(app.LGNFigure) || ~isvalid(app.LGNFigure)
                buildLGNWindow(app);
            end
            app.LGNFigure.Visible = 'on';
        end


        function lgnFigureClosed(app)
            delete(app.LGNFigure);
            app.LGNFigure = [];
            delete(app);
        end

        function mainFigureClosed(app)
            if ~isempty(app.LGNFigure) && isvalid(app.LGNFigure)
                delete(app.LGNFigure);
            end
            delete(app);
        end

        function buildLGNWindow(app)
            fw = 1040; fh = 740;
            scr = get(groot, 'ScreenSize');

            app.LGNFigure = uifigure('Name', 'AxonSkewer - dLGN', ...
                'Position', [scr(3)/2 - fw/2, max(40, scr(4)/2 - fh/2), fw, fh], ...
                'CloseRequestFcn', @(s,e) lgnFigureClosed(app));
            app.LGNFigure.WindowButtonDownFcn = @(s,e) lgnMouseClick(app, s, e);

            g = uigridlayout(app.LGNFigure, [3 2]);
            g.ColumnWidth = {'1x', 250};
            g.RowHeight   = {'1x', 70, 55};

            % ---- image axes ----
            app.LGNAxes = uiaxes(g);
            app.LGNAxes.Layout.Row = 1;
            app.LGNAxes.Layout.Column = 1;
            title(app.LGNAxes, 'dLGN - raw image');
            app.LGNAxes.FontName = 'Lucida Bright';
            app.LGNAxes.XTick = [];
            app.LGNAxes.YTick = [];

            % ---- image path row ----
            p1 = uigridlayout(g, [1 2]);
            p1.Layout.Row = 2;  p1.Layout.Column = 1;
            p1.ColumnWidth = {80, '1x'};  p1.RowHeight = {'1x'};
            p1.Padding = [0 0 0 0];
            lb1 = uilabel(p1, 'Text', 'Image path', 'FontName', 'Lucida Bright');
            lb1.HorizontalAlignment = 'right';
            app.LGNPathArea = uitextarea(p1, 'FontName', 'Lucida Bright', 'Editable', 'off');

            % ---- status row ----
            p2 = uigridlayout(g, [1 2]);
            p2.Layout.Row = 3;  p2.Layout.Column = 1;
            p2.ColumnWidth = {80, '1x'};  p2.RowHeight = {'1x'};
            p2.Padding = [0 0 0 0];
            lb2 = uilabel(p2, 'Text', 'Status', 'FontName', 'Lucida Bright');
            lb2.HorizontalAlignment = 'right';
            app.LGNStatusArea = uitextarea(p2, 'FontName', 'Lucida Bright', 'Editable', 'off');

            % ---- control column ----
            rowH = {20,26, ...                  % Image
                20,26,26,26, ...            % dLGN loop
                20,26,26,26, ...            % Lateral line
                20,26,26,26, ...            % Medial line
                20,26,26,26, ...            % DM pole
                20,26,26,26, ...            % VL pole
                20,28, '1x'};               % Output
            c = uigridlayout(g, [numel(rowH) 1]);
            c.Layout.Row = [1 3];
            c.Layout.Column = 2;
            c.RowHeight   = rowH;
            c.ColumnWidth = {'1x'};
            c.RowSpacing  = 4;

            hdr = @(txt) uilabel(c, 'Text', txt, 'FontName', 'Lucida Bright', ...
                'FontWeight', 'bold', 'FontSize', 11);
            mkb = @(txt, cb) uibutton(c, 'push', 'Text', txt, ...
                'FontName', 'Lucida Bright', 'ButtonPushedFcn', cb);

            hdr('Image');
            app.LGNBtn.load     = mkb('Load raw',       @(s,e) lgnLoadRaw(app));

            hdr('dLGN loop');
            app.LGNBtn.draw     = mkb('Draw loop',      @(s,e) lgnStartDraw(app));
            app.LGNBtn.close    = mkb('Close loop',     @(s,e) lgnCloseLoop(app));
            app.LGNBtn.clear    = mkb('Clear loop',     @(s,e) lgnClearLoop(app));

            hdr('Lateral line');
            app.LGNBtn.latStart = mkb('Label lateral',  @(s,e) lgnStartLabel(app,'lateral'));
            app.LGNBtn.latDone  = mkb('Done lateral',   @(s,e) lgnDoneLabel(app,'lateral'));
            app.LGNBtn.latClear = mkb('Clear lateral',  @(s,e) lgnClearLabel(app,'lateral'));

            hdr('Medial line');
            app.LGNBtn.medStart = mkb('Label medial',   @(s,e) lgnStartLabel(app,'medial'));
            app.LGNBtn.medDone  = mkb('Done medial',    @(s,e) lgnDoneLabel(app,'medial'));
            app.LGNBtn.medClear = mkb('Clear medial',   @(s,e) lgnClearLabel(app,'medial'));

            hdr('Dorsal-medial pole');
            app.LGNBtn.dmpStart = mkb('Set DM pole',    @(s,e) lgnStartLabel(app,'dmpole'));
            app.LGNBtn.dmpDone  = mkb('Done DM pole',   @(s,e) lgnDoneLabel(app,'dmpole'));
            app.LGNBtn.dmpClear = mkb('Clear DM pole',  @(s,e) lgnClearLabel(app,'dmpole'));

            hdr('Ventral-lateral pole');
            app.LGNBtn.vlpStart = mkb('Set VL pole',    @(s,e) lgnStartLabel(app,'vlpole'));
            app.LGNBtn.vlpDone  = mkb('Done VL pole',   @(s,e) lgnDoneLabel(app,'vlpole'));
            app.LGNBtn.vlpClear = mkb('Clear VL pole',  @(s,e) lgnClearLabel(app,'vlpole'));

            hdr('Output');
            app.LGNBtn.save     = mkb('Save & output',  @(s,e) lgnSaveOutput(app));

            lgnStatus(app, 'Load a raw image to begin.');
            updateLGNControls(app);
        end
        function updateLGNControls(app)
           % Single place that decides which buttons are live, so illegal states
            % (e.g. drawing a second loop) are simply not reachable.
            if isempty(app.LGNFigure) || ~isvalid(app.LGNFigure)
                return
            end
 
            hasImg    = ~isempty(app.LGNImgPath);
            hasLoop   = size(app.LGNLoop,1) > 0;
            closed    = app.LGNLoopClosed;
            drawing   = strcmp(app.LGNMode, 'drawing');
            labelling = any(strcmp(app.LGNMode, {'lateral','medial','dmpole','vlpole'}));
            busy      = drawing || labelling;
 
            app.LGNBtn.load.Enable  = ~busy;
            app.LGNBtn.draw.Enable  = hasImg && ~closed && ~busy;   % no 2nd loop while one exists
            app.LGNBtn.close.Enable = drawing && size(app.LGNLoop,1) >= 3;
            app.LGNBtn.clear.Enable = hasLoop && ~labelling;
 
            app.LGNBtn.latStart.Enable = closed && ~busy;
            app.LGNBtn.latDone.Enable  = strcmp(app.LGNMode, 'lateral');
            app.LGNBtn.latClear.Enable = closed && ~drawing && ~isempty(app.LGNLatIdx);
 
            app.LGNBtn.medStart.Enable = closed && ~busy;
            app.LGNBtn.medDone.Enable  = strcmp(app.LGNMode, 'medial');
            app.LGNBtn.medClear.Enable = closed && ~drawing && ~isempty(app.LGNMedIdx);
 
            app.LGNBtn.dmpStart.Enable = closed && ~busy;
            app.LGNBtn.dmpDone.Enable  = strcmp(app.LGNMode, 'dmpole');
            app.LGNBtn.dmpClear.Enable = closed && ~drawing && ~isempty(app.LGNDMPoleIdx);
 
            app.LGNBtn.vlpStart.Enable = closed && ~busy;
            app.LGNBtn.vlpDone.Enable  = strcmp(app.LGNMode, 'vlpole');
            app.LGNBtn.vlpClear.Enable = closed && ~drawing && ~isempty(app.LGNVLPoleIdx);
 
            app.LGNBtn.save.Enable = closed && ~busy;
        end

        function lgnStatus(app, msg)
            if ~isempty(app.LGNStatusArea) && isvalid(app.LGNStatusArea)
                app.LGNStatusArea.Value = msg;
            end
            fprintf('[dLGN] %s\n', msg);
        end

        function lgnLoadRaw(app)
            [f, p] = uigetfile({'*.tif;*.tiff', 'TIFF images (*.tif, *.tiff)'});
            if isequal(f, 0); return; end

            app.LGNImgPath = fullfile(p, f);
            im = imread(app.LGNImgPath);
            if size(im,3) == 1
                im = repmat(im, 1, 1, 3);
            end

            lgnClearLoop(app);              % a new image invalidates any existing annotation

            cla(app.LGNAxes);
            image(app.LGNAxes, im);
            axis(app.LGNAxes, 'image');
            app.LGNAxes.XTick = [];
            app.LGNAxes.YTick = [];
            app.LGNAxes.YDir  = 'reverse';  % row increases downward, as in the SCs window
            hold(app.LGNAxes, 'on');

            % Make the image itself unclickable so CurrentObject resolves to the axes.
            hImg = findobj(app.LGNAxes, 'Type', 'Image');
            set(hImg, 'HitTest', 'off', 'PickableParts', 'none');

            app.LGNPathArea.Value = app.LGNImgPath;
            lgnStatus(app, 'Image loaded. Press "Draw loop" to start tracing the dLGN border.');
            updateLGNControls(app);
        end

        function lgnStartDraw(app)
           if app.LGNLoopClosed
                lgnStatus(app, 'A closed loop already exists. Press "Clear loop" first.');
                return
            end
            app.LGNMode = 'drawing';
            lgnStatus(app, 'Drawing: click along the dLGN border. Press "Close loop" when done.');
            updateLGNControls(app);
        end

        function lgnAddVertex(app, x, y)
            app.LGNLoop(end+1,:) = [x y];
            k = size(app.LGNLoop, 1);

            h = plot(app.LGNAxes, x, y, 'o', 'MarkerSize', 6, ...
                'Color', app.LGNColUnlabeled, 'LineWidth', 1.2);
            app.LGNVertexH(k) = h;

            if k > 1
                e = line(app.LGNAxes, app.LGNLoop(k-1:k,1), app.LGNLoop(k-1:k,2), ...
                    'Color', 'r', 'LineWidth', 2);
                app.LGNEdgeH(end+1) = e;
            end
            updateLGNControls(app);
        end

        function lgnCloseLoop(app)
            n = size(app.LGNLoop, 1);
            if n < 3
                lgnStatus(app, sprintf('Need at least 3 points to close a loop (currently %d).', n));
                return
            end
            % the closing edge: last vertex back to the first
            e = line(app.LGNAxes, [app.LGNLoop(end,1) app.LGNLoop(1,1)], ...
                                  [app.LGNLoop(end,2) app.LGNLoop(1,2)], ...
                     'Color', 'r', 'LineWidth', 2);
            app.LGNEdgeH(end+1) = e;
 
            app.LGNLoopClosed = true;
            app.LGNMode = 'idle';
            lgnStatus(app, sprintf(['Loop closed with %d vertices. Now label the lateral and ' ...
                                    'medial lines, and mark at least one pole.'], n));
            updateLGNControls(app);
        end

        function lgnClearLoop(app)
            if ~isempty(app.LGNVertexH)
                delete(app.LGNVertexH(isgraphics(app.LGNVertexH)));
            end
            if ~isempty(app.LGNEdgeH)
                delete(app.LGNEdgeH(isgraphics(app.LGNEdgeH)));
            end
            app.LGNVertexH    = gobjects(0);
            app.LGNEdgeH      = gobjects(0);
            app.LGNLoop       = zeros(0,2);
            app.LGNLoopClosed = false;
            app.LGNLatIdx     = [];
            app.LGNMedIdx     = [];
            app.LGNDMPoleIdx  = [];
            app.LGNVLPoleIdx  = [];
            app.LGNMode       = 'idle';
            lgnStatus(app, 'Loop and all labels cleared.');
            updateLGNControls(app);
        end

        % ------------------------------------------------------------------
        %  dLGN - labelling
        % ------------------------------------------------------------------

        function lgnStartLabel(app, which)
            if ~app.LGNLoopClosed
                lgnStatus(app, 'Close the loop before labelling.');
                return
            end
            app.LGNMode = which;
            switch which
                case 'lateral'
                    lgnStatus(app, ['Lateral mode: click dots on the loop. Clicking a selected ' ...
                        'dot again removes it. Press "Done lateral" when finished.']);
                case 'medial'
                    lgnStatus(app, ['Medial mode: click dots on the loop. Clicking a selected ' ...
                        'dot again removes it. Press "Done medial" when finished.']);
                case 'dmpole'
                    lgnStatus(app, ['DM pole mode: click one dot on the dorsal-medial arc. ' ...
                        'Clicking another dot moves the pole there; clicking the ' ...
                        'pole itself clears it. Press "Done DM pole" when finished.']);
                case 'vlpole'
                    lgnStatus(app, ['VL pole mode: click one dot on the ventral-lateral arc. ' ...
                        'Clicking another dot moves the pole there; clicking the ' ...
                        'pole itself clears it. Press "Done VL pole" when finished.']);
            end
            updateLGNControls(app);
        end

        function lgnDoneLabel(app, which)
        app.LGNMode = 'idle';
            switch which
                case 'lateral'
                    [~, msg] = lgnCheckRun(app, app.LGNLatIdx, 'Lateral line');
                case 'medial'
                    [~, msg] = lgnCheckRun(app, app.LGNMedIdx, 'Medial line');
                case 'dmpole'
                    if isempty(app.LGNDMPoleIdx)
                        msg = 'DM pole: not set (mark the VL pole instead if it is not visible).';
                    else
                        msg = sprintf('DM pole: vertex %d.', app.LGNDMPoleIdx);
                    end
                case 'vlpole'
                    if isempty(app.LGNVLPoleIdx)
                        msg = 'VL pole: not set (mark the DM pole instead if it is not visible).';
                    else
                        msg = sprintf('VL pole: vertex %d.', app.LGNVLPoleIdx);
                    end
            end
            lgnStatus(app, msg);
            updateLGNControls(app);
        end

        function lgnClearLabel(app, which)
            switch which
                case 'lateral'
                    idx = app.LGNLatIdx;      app.LGNLatIdx     = [];
                case 'medial'
                    idx = app.LGNMedIdx;      app.LGNMedIdx     = [];
                case 'dmpole'
                    idx = app.LGNDMPoleIdx;   app.LGNDMPoleIdx  = [];
                case 'vlpole'
                    idx = app.LGNVLPoleIdx;   app.LGNVLPoleIdx  = [];
            end
            for k = idx(:)'
                lgnPaintVertex(app, k);    % repaint back to the unlabelled colour
            end
            lgnStatus(app, sprintf('Cleared all %s dots.', which));
            updateLGNControls(app);
        end

        function lgnPaintVertex(app, k)
            % Recolour one vertex marker according to whichever label it currently holds.
            if k < 1 || k > numel(app.LGNVertexH) || ~isgraphics(app.LGNVertexH(k))
                return
            end
            h = app.LGNVertexH(k);
            if isequal(app.LGNDMPoleIdx, k)
                set(h, 'Color', app.LGNColDMPole, 'MarkerFaceColor', app.LGNColDMPole, 'MarkerSize', 11);
            elseif isequal(app.LGNVLPoleIdx, k)
                set(h, 'Color', app.LGNColVLPole, 'MarkerFaceColor', app.LGNColVLPole, 'MarkerSize', 11);
            elseif any(app.LGNLatIdx == k)
                set(h, 'Color', app.LGNColLateral, 'MarkerFaceColor', app.LGNColLateral, 'MarkerSize', 7);
            elseif any(app.LGNMedIdx == k)
                set(h, 'Color', app.LGNColMedial, 'MarkerFaceColor', app.LGNColMedial, 'MarkerSize', 7);
            else
                set(h, 'Color', app.LGNColUnlabeled, 'MarkerFaceColor', 'none', 'MarkerSize', 6);
            end
        end

        function lgnMouseClick(app, src, ~)
        if strcmp(app.LGNMode, 'idle')
                return
            end
 
            % Only react to clicks that landed inside the dLGN axes.
            obj = src.CurrentObject;
            ax  = [];
            if ~isempty(obj) && isgraphics(obj)
                ax = ancestor(obj, 'matlab.graphics.axis.Axes');
            end
            if isempty(ax) || ax ~= app.LGNAxes
                return
            end
 
            cp = app.LGNAxes.CurrentPoint;
            x = cp(1,1);  y = cp(1,2);
            xl = app.LGNAxes.XLim;  yl = app.LGNAxes.YLim;
            if x < xl(1) || x > xl(2) || y < yl(1) || y > yl(2)
                return
            end
 
            if strcmp(app.LGNMode, 'drawing')
                lgnAddVertex(app, x, y);
            else
                lgnLabelNearest(app, x, y);
            end
        end

        function lgnDetachVertex(app, k)
            % Remove vertex k from every label category. The four categories are
            % mutually exclusive, so a dot always leaves its old label before taking
            % a new one.
            app.LGNLatIdx(app.LGNLatIdx == k) = [];
            app.LGNMedIdx(app.LGNMedIdx == k) = [];
            if isequal(app.LGNDMPoleIdx, k); app.LGNDMPoleIdx = []; end
            if isequal(app.LGNVLPoleIdx, k); app.LGNVLPoleIdx = []; end
        end

        function lgnLabelNearest(app, x, y)
            if isempty(app.LGNLoop); return; end

            d = hypot(app.LGNLoop(:,1) - x, app.LGNLoop(:,2) - y);
            [dmin, k] = min(d);

            % SNAP TOLERANCE
            % A click only counts if it lands "very close" to an existing dot; otherwise
            % it is ignored, so a stray click cannot silently relabel a distant vertex.
            % "Very close" is defined relative to the displayed image rather than in
            % screen pixels, so it behaves identically for a 512x512 and a 2048x2048
            % image, and it follows you if you zoom:
            %     tol = LGNSnapTolFrac * (diagonal of the currently displayed region)
            % At the default 0.02 that is 2% of the diagonal - roughly 15 px on a
            % 512x512 image. Raise LGNSnapTolFrac if you find yourself missing dots.
            tol = app.LGNSnapTolFrac * hypot(diff(app.LGNAxes.XLim), diff(app.LGNAxes.YLim));
            if dmin > tol
                lgnStatus(app, 'Click ignored - not close enough to a loop vertex.');
                return
            end

            switch app.LGNMode
                case 'lateral', already = any(app.LGNLatIdx == k);
                case 'medial',  already = any(app.LGNMedIdx == k);
                case 'dmpole',  already = isequal(app.LGNDMPoleIdx, k);
                case 'vlpole',  already = isequal(app.LGNVLPoleIdx, k);
                otherwise,      return
            end

            % Dots that may need repainting: k itself, plus whichever dot currently
            % holds the single-vertex label we are about to move.
            touched = k;
            if strcmp(app.LGNMode, 'dmpole') && ~isempty(app.LGNDMPoleIdx)
                touched(end+1) = app.LGNDMPoleIdx;
            end
            if strcmp(app.LGNMode, 'vlpole') && ~isempty(app.LGNVLPoleIdx)
                touched(end+1) = app.LGNVLPoleIdx;
            end

            lgnDetachVertex(app, k);

            if ~already
                switch app.LGNMode
                    case 'lateral', app.LGNLatIdx(end+1) = k;
                    case 'medial',  app.LGNMedIdx(end+1) = k;
                    case 'dmpole',  app.LGNDMPoleIdx = k;  % single: it moves to the new dot
                    case 'vlpole',  app.LGNVLPoleIdx = k;  % single: it moves to the new dot
                end
            end

            for t = unique(touched)
                lgnPaintVertex(app, t);
            end
            updateLGNControls(app);
        end

        % ------------------------------------------------------------------
        %  dLGN - geometry helpers
        % ------------------------------------------------------------------

        function runs = lgnCyclicRuns(~, mask)
           % Split a logical mask defined on a CLOSED loop into maximal runs of
            % consecutive true entries, wrapping from the last element back to the
            % first. Returns a cell array of column index vectors, each already in
            % loop order (so a run that wraps comes out as e.g. [N-1; N; 1; 2]).
            n = numel(mask);
            mask = logical(mask(:));
            runs = {};
            if ~any(mask); return; end
            if all(mask);  runs = {(1:n)'};  return; end
 
            prev   = circshift(mask, 1);
            starts = find(mask & ~prev);
            for s = starts(:)'
                idx = s;
                j   = s;
                while true
                    j = mod(j, n) + 1;
                    if ~mask(j); break; end
                    idx(end+1) = j; %#ok<AGROW>
                end
                runs{end+1} = idx(:); %#ok<AGROW>
            end
        end

        function [ok, msg] = lgnCheckRun(app, idx, name)
            % A labelled line must be one continuous run of dots along the loop.
            n = size(app.LGNLoop, 1);
            if isempty(idx)
                ok = false;
                msg = sprintf('%s: no dots selected.', name);
                return
            end
            mask = false(n,1);
            mask(idx) = true;
            runs = lgnCyclicRuns(app, mask);
            if numel(runs) == 1
                ok  = true;
                msg = sprintf('%s: %d dots, continuous. OK.', name, numel(idx));
            else
                ok  = false;
                msg = sprintf('%s: %d dots in %d separate pieces - must be one continuous run.', ...
                              name, numel(idx), numel(runs));
            end
        end

        % ------------------------------------------------------------------
        %  dLGN - save
        % ------------------------------------------------------------------

        function lgnSaveOutput(app)
            n = size(app.LGNLoop, 1);
 
            % ---------------- validation ----------------
            if ~app.LGNLoopClosed
                lgnStatus(app, 'Cannot save: the loop is not closed.');
                return
            end
            [okL, msgL] = lgnCheckRun(app, app.LGNLatIdx, 'Lateral line');
            if ~okL
                lgnStatus(app, ['Cannot save. ' msgL]);
                return
            end
            [okM, msgM] = lgnCheckRun(app, app.LGNMedIdx, 'Medial line');
            if ~okM
                lgnStatus(app, ['Cannot save. ' msgM]);
                return
            end
            if isempty(app.LGNDMPoleIdx) && isempty(app.LGNVLPoleIdx)
                lgnStatus(app, ['Cannot save: mark at least one pole. Either one is enough ' ...
                    'to tell the two unlabelled arcs apart, so if the dorsal-medial pole is ' ...
                    'not visible, mark the ventral-lateral pole instead.']);
                return
            end

            % The lateral and medial runs must not touch, so the unlabelled dots have
            % to fall into exactly TWO arcs. Anything else means the labelling is
            % geometrically wrong and the DM / VL split below would be meaningless.
            labelled = false(n,1);
            labelled(app.LGNLatIdx) = true;
            labelled(app.LGNMedIdx) = true;
            gaps = lgnCyclicRuns(app, ~labelled);
            if numel(gaps) ~= 2
                lgnStatus(app, sprintf(['Cannot save: the lateral and medial lines leave %d ' ...
                    'unlabelled arc(s) on the loop; there must be exactly 2 (the two lines ' ...
                    'must not touch each other).'], numel(gaps)));
                return
            end

           % ---------------- identify the two arcs ----------------
            % Each pole names the arc it sits on; the other arc follows by elimination.
            % So ONE pole is sufficient, which is what lets an image with an invisible
            % dorsal-medial pole still produce a fully labelled dm_idx and vl_idx.
            % If both poles are marked they must disagree about which arc they are on -
            % otherwise the annotation contradicts itself and is refused.
            dmGap = 0;   % which gap (1 or 2) is the dorsal-medial arc
            vlGap = 0;   % which gap (1 or 2) is the ventral-lateral arc
            if ~isempty(app.LGNDMPoleIdx)
                if     any(gaps{1} == app.LGNDMPoleIdx), dmGap = 1;
                elseif any(gaps{2} == app.LGNDMPoleIdx), dmGap = 2;
                else
                    lgnStatus(app, 'Cannot save: the DM pole is not on an unlabelled arc.');
                    return
                end
            end
            if ~isempty(app.LGNVLPoleIdx)
                if     any(gaps{1} == app.LGNVLPoleIdx), vlGap = 1;
                elseif any(gaps{2} == app.LGNVLPoleIdx), vlGap = 2;
                else
                    lgnStatus(app, 'Cannot save: the VL pole is not on an unlabelled arc.');
                    return
                end
            end
            if dmGap ~= 0 && vlGap ~= 0 && dmGap == vlGap
                lgnStatus(app, ['Cannot save: the DM pole and the VL pole are on the SAME ' ...
                    'unlabelled arc. They have to be on opposite arcs.']);
                return
            end
            if dmGap == 0
                dmGap = 3 - vlGap;      % only the VL pole was marked
            elseif vlGap == 0
                vlGap = 3 - dmGap;      % only the DM pole was marked
            end
 
            % Re-derive the lateral / medial index lists as runs so that they come out
            % ordered along the loop, including the case where a run wraps past vertex N.
            maskL = false(n,1);  maskL(app.LGNLatIdx) = true;
            maskM = false(n,1);  maskM(app.LGNMedIdx) = true;
            runL = lgnCyclicRuns(app, maskL);
            runM = lgnCyclicRuns(app, maskM);
 
            % A pole that was not marked is saved as NaN. Note that only the POINT is
            % lost - dm_idx and vl_idx are still fully determined.
            if isempty(app.LGNDMPoleIdx), dmPoleOut = NaN; else, dmPoleOut = app.LGNDMPoleIdx; end
            if isempty(app.LGNVLPoleIdx), vlPoleOut = NaN; else, vlPoleOut = app.LGNVLPoleIdx; end
 
            % ---------------- assemble output ----------------
            % The vertex COORDINATES are stored exactly once, in loop_xy, in the order
            % they were clicked - that order is what defines the loop's connectivity.
            % Every label is then stored as INDICES into loop_xy rather than as its own
            % copy of the coordinates. Two reasons for choosing indices over coordinate
            % lists: (1) the labels can never drift out of sync with the loop, since
            % there is only one copy of the geometry; (2) the ordering along the loop
            % stays explicit, which is what makes "continuous arc" a meaningful check.
            % Downstream you get coordinates back with:
            %     lateral_xy = result.loop_xy(result.lateral_idx, :);
            result = struct();
            result.region      = 'dLGN';      % lets one loader handle SCs and dLGN files
            result.loop_xy     = app.LGNLoop; % N-by-2, click order, closed (N connects to 1)
            result.lateral_idx = runL{1};     % clicked
            result.medial_idx  = runM{1};     % clicked
            result.dm_pole_idx = dmPoleOut;   % clicked single vertex, or NaN if not visible
            result.vl_pole_idx = vlPoleOut;   % clicked single vertex, or NaN if not visible
            result.dm_idx      = gaps{dmGap}; % derived: the dorsal-medial arc
            result.vl_idx      = gaps{vlGap}; % derived: the ventral-lateral arc
 
            [folder, ~, ~] = fileparts(app.LGNImgPath);
            fp = fullfile(folder, 'dLGN_annot.mat');
            save(fp, 'result');
 
            if isnan(dmPoleOut)
                lgnStatus(app, sprintf('Saved (arcs from the VL pole; dm_pole_idx = NaN): %s', fp));
            elseif isnan(vlPoleOut)
                lgnStatus(app, sprintf('Saved (arcs from the DM pole; vl_pole_idx = NaN): %s', fp));
            else
                lgnStatus(app, sprintf('Saved (both poles marked): %s', fp));
            end
        end

        function onMouseClick(app, src, evt) %function dealing with drawing SC border
            if ~app.isDrawing
                return
            end

            clickedObj = src.CurrentObject;   % could be the axes, an image primitive, a button, etc.
            ax = [];
            if ~isempty(clickedObj) && isgraphics(clickedObj)
                ax = ancestor(clickedObj, 'matlab.graphics.axis.Axes');
            end

            % If the click was not inside our UIAxes1, ignore it
            if isempty(ax) || ax ~= app.UIAxes1
                return;
            end

            cp =ax.CurrentPoint;
            x = cp(1,1);
            y = cp(1,2);

            app.clickedPts(end+1,:) = [x y];
            g = plot(app.UIAxes1, x, y, 'ro', 'MarkerSize',6);
            app.markerHandles(end+1) = g;
            if size(app.clickedPts,1) > 1
                p1 = app.clickedPts(end-1,:);
                p2 = app.clickedPts(end,:);

                h = line(app.UIAxes1, ...
                    [p1(1) p2(1)], [p1(2) p2(2)], ...
                    'Color','r','LineWidth',2);
                app.drawnLineHandles(end+1) = h;
            end
        end

        function [tl, segl] = border_seg_length(app)
            [ptn, ~] = size(app.clickedPts);
            %ptn = numel(app.clickedPts)/2;
            tl = 0;
            segl = zeros([ptn-1, 1]);
            for i =2:ptn
                v = app.clickedPts(i, :)- app.clickedPts(i-1, :);
                %l = l + norm(v);
                segl(i-1) = norm(v);
                tl = tl+segl(i-1);
            end
            fprintf('Total segement length: %d\n', tl);
        end

        function calculate_orthognals(app)
            app.orthoAll = [];
            [total_len, seglen] = border_seg_length(app);
            %tidx = 0;
            for i = 1:numel(seglen)
                p1 = app.clickedPts(i, :);
                p2 = app.clickedPts(i+1, :);
                d = p2-p1;
                if (seglen(i) == 0)
                    warning('Segment %d: p1 and p2 are identical, skipping.', i);
                    %app.orthoAll{i} = [];
                    continue;
                end

                n_orth = ceil(seglen(i)/total_len * app.totalOrthL);
                if (n_orth==0)
                    n_orth = 1;
                end

                t = d/seglen(i);
                normal = [-t(2), t(1)];

                s = (1:n_orth)/(n_orth+1);
                % Points along the segment
                pts = p1 + s(:) * d;  % n-by-2
                % Orthogonal line passes through pts(i) with direction 'normal'
                % Parametric form: [x, y] = pts(i) + t * normal
                slopes_intercepts = zeros(n_orth, 5);
                for j = 1:n_orth
                    %tidx = tidx+1;
                    x0 = pts(j, 1);
                    y0 = pts(j, 2);

                    nx = normal(1);
                    ny = normal(2);

                    if abs(nx) < eps  % vertical line
                        % For vertical line, store as inf slope
                        slopes_intercepts(i, 1) = inf;
                        slopes_intercepts(i, 2) = x0;  % x-coordinate of vertical line
                    else
                        slope = ny / nx;
                        intercept = y0 - slope * x0;
                        slopes_intercepts(j, 1) = slope;
                        slopes_intercepts(j, 2) = intercept;
                        slopes_intercepts(j,3) = x0;
                        slopes_intercepts(j,4) = y0;
                        %slopes_intercepts(j, 5)
                    end
                end
                app.orthoAll = [app.orthoAll; slopes_intercepts];
                disp(slopes_intercepts);
            end
        end

        function keyPressCallback(app,eventdata)
            if (app.isSelecting == false)
                return
            end
            if strcmp(eventdata.Key, 'g')
                % Get current mouse position in axes
                current_pt = get(app.UIAxes2, 'CurrentPoint');
                cursor_x = current_pt(1, 1);
                cursor_y = current_pt(1, 2);

                % Find the closest line to the cursor
                min_distance = inf;
                closest_line_idx = -1;
                %lineHandles = app.orthoLineHandles;
                %[n_orth,~] = size(app.orthoAll);

                for i = 1:length(app.orthoLineHandles)
                    m = app.orthoAll(i, 1);
                    b = app.orthoAll(i, 2);
                    distance = abs(m*cursor_x - cursor_y + b)/sqrt(m^2+1);
                    if (distance<min_distance)
                        min_distance = distance;
                        closest_line_idx = i;
                    end
                    % % Get line data
                    % line_x = get(lineHandles{i}, 'XData');
                    % line_y = get(lineHandles{i}, 'YData');
                    %
                    % % Calculate distance from cursor to each point on the line
                    % distances = sqrt((line_x - cursor_x).^2 + (line_y - cursor_y).^2);
                    % min_dist_to_line = min(distances);
                    %
                    % % Track closest line
                    % if min_dist_to_line < min_distance
                    %     min_distance = min_dist_to_line;
                    %     closest_line_idx = i;
                    % end
                end

                %delete the previously selected line
                if (~isempty(app.selectedOrtho))
                    handle = app.orthoLineHandles(app.selectedIdx);
                    set(handle, 'Color', 'yellow');
                    app.selectedIdx = [];
                    app.selectedOrtho = {};
                end

                handle =app.orthoLineHandles(closest_line_idx);
                set(handle, 'Color', 'cyan');
                app.selectedIdx = closest_line_idx;
                app.selectedOrtho = app.orthoAll(closest_line_idx, :);
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.clickedPts        = [];
            app.drawnLineHandles  = gobjects(0);
            app.orthoLineHandles  = gobjects(0);
            app.drawnLineHandles2 = gobjects(0);
            app.markerHandles2    = gobjects(0);
 
            % App Designer's generated delete() cannot be edited, so the dLGN window is
            % cleaned up here instead when the SCs window is closed.
            app.UIFigure.CloseRequestFcn = @(s,e) mainFigureClosed(app);
 
            chooseRegion(app);   % blocks until SCs or dLGN is chosen
        end

        % Button pushed function: LoadrawButton
        function LoadrawButtonPushed(app, event)
            [f, p] = uigetfile('*.tif');
            if isequal(f,0); return; end

            app.img1Path = fullfile(p,f);
            imbuffer = imread(app.img1Path);
            img1_uint8 = imbuffer;
            %img1_uint8 =im2uint8(mat2gray(imbuffer));
            if ndims(img1_uint8) == 2
                img1_uint8 = repmat(img1_uint8, 1, 1, 3);
            end
            %app.Image1.ImageSource = img1_uint8;
            imshow(img1_uint8, 'Parent', app.UIAxes1);
            app.ImagepathTextArea.Value = app.img1Path;
            hImg = findobj(app.UIAxes1, 'Type', 'Image');
            set(hImg, 'HitTest', 'off', 'PickableParts', 'none');

            %display in axis as well?
            cla(app.UIAxes1);                       % clear previous contents
            image(app.UIAxes1, img1_uint8);         % show RGB uint8 image
            axis(app.UIAxes1, 'image');             % preserve aspect ratio / pixel aspect
            app.UIAxes1.XTick = [];                 % hide tick marks
            app.UIAxes1.YTick = [];
            app.UIAxes1.YDir = 'reverse';           % make (x,y) clicks intuitive (row increases downward)
            hold(app.UIAxes1, 'on');                % allow overlaying lines/markers

            try
                app.UIAxes1.Color = 'none';
                uistack(app.UIAxes1, 'top');
            catch
                % older versions / layout may not support uistack/layout change — ignore if fails
            end
        end

        % Button pushed function: LoadskeletonButton
        function LoadskeletonButtonPushed(app, event)
            [f, p] = uigetfile('*.tif');
            if isequal(f,0); return; end

            im2path = fullfile(p,f);
            imbuffer = imread(im2path);
            im2_uint8 = imbuffer;
            %img2_uint8 =im2uint8(mat2gray(imbuffer));
            if ndims(im2_uint8) == 2
                im2_uint8 = repmat(im2_uint8, 1, 1, 3);
            end
            imshow(im2_uint8, 'Parent', app.UIAxes2);
            hImg = findobj(app.UIAxes2, 'Type', 'Image');
            set(hImg, 'HitTest', 'off', 'PickableParts', 'none');
            cla(app.UIAxes2);                       % clear previous contents
            image(app.UIAxes2, im2_uint8);         % show RGB uint8 image
            axis(app.UIAxes2, 'image');             % preserve aspect ratio / pixel aspect
            app.UIAxes2.XTick = [];                 % hide tick marks
            app.UIAxes2.YTick = [];
            app.UIAxes2.YDir = 'reverse';           % make (x,y) clicks intuitive (row increases downward)
            %hold(app.UIAxes2, 'on');
        end

        % Button pushed function: DrawoutlineButton
        function DrawoutlineButtonPushed(app, event)
            app.isDrawing = true;
            app.clickedPts = [];
            % Clear existing graphics safely
            if ~isempty(app.drawnLineHandles)
                delete(app.drawnLineHandles);
            end
            if ~isempty(app.markerHandles)
                delete(app.markerHandles);
            end

            app.markerHandles = gobjects(0);
            app.drawnLineHandles = gobjects(0);
            app.UIFigure.WindowButtonDownFcn = @(src,evt) onMouseClick(app, src, evt);

            % Make sure axis is on top and ready for overlays
            try
                app.UIAxes1.Color = 'none';
                uistack(app.UIAxes1, 'top');
            catch
            end
            hold(app.UIAxes1, 'on');
        end

        % Button pushed function: SaveoutlineButton
        function SaveoutlineButtonPushed(app, event)
            hold(app.UIAxes1, 'off');
            app.isDrawing = false;
            app.UIFigure.WindowButtonDownFcn = [];
            calculate_orthognals(app);
            disp(app.orthoAll);
        end

        % Value changed function: TotalorthogonalEditField
        function TotalorthogonalEditFieldValueChanged(app, event)
            comp = app.TotalorthogonalEditField.Value;
            val = comp;
            if isnumeric(val) && isscalar(val) && ~isnan(val)
                app.totalOrthL = val;
            else
                if ischar(val) || isstring(val)
                    val = str2double(val);
                    if ~isnan(val)
                        app.totalOrthL = val;
                    end
                end
            end
        end

        % Button pushed function: DeleteoutlineButton
        function DeleteoutlineButtonPushed(app, event)
            app.clickedPts = [];
            % Clear existing graphics
            if ~isempty(app.drawnLineHandles)
                delete(app.drawnLineHandles);
            end
            if ~isempty(app.markerHandles)
                delete(app.markerHandles);
            end
            if ~isempty(app.orthoLineHandles)
                delete(app.orthoLineHandles);
            end
        end

        % Button pushed function: DisplayonskeletonButton
        function DisplayonskeletonButtonPushed(app, event)
            % Get axes limits to determine line endpoints
            axes(app.UIAxes2);
            xlim = get(app.UIAxes2, 'XLim');
            ylim = get(app.UIAxes2, 'YLim');
            xmin = xlim(1);
            xmax = xlim(2);
            if (~isempty(app.orthoLineHandles))
                delete(app.orthoLineHandles);
                delete(app.markerHandles2);
                delete(app.drawnLineHandles2);
                app.orthoLineHandles = gobjects(0);
                app.markerHandles2 = gobjects(0);
                app.drawnLineHandles2 = gobjects(0);
            end
            hold(app.UIAxes2, 'on');
            [dotsn, ~] = size(app.clickedPts);
            seg_n = dotsn-1;
            %plotting the clicked dots AND the line between them
            for i = 1:dotsn
                d = plot(app.UIAxes2, app.clickedPts(i,1), app.clickedPts(i,2), 'ro', 'MarkerSize',6);
                app.markerHandles2(end+1) = d;
            end
            for i = 1:seg_n
                p1 = app.clickedPts(i, :);
                p2 = app.clickedPts(i+1, :);
                h = line(app.UIAxes2, [p1(1) p2(1)], [p1(2), p2(2)],  'Color','r','LineWidth',1.5);
                app.drawnLineHandles2(end+1) = h;
            end

            %calculate_orthognals(app);
            %getting the slope and intercpts from the app variable
            [ron, ~] =size(app.orthoAll);
            for j = 1:ron
                slope = app.orthoAll(j, 1);
                intercept = app.orthoAll(j, 2);
                y_at_xmin = slope*xmin + intercept;
                y_at_xmax = slope*xmax + intercept;
                l = plot(app.UIAxes2, [xmin xmax], [y_at_xmin y_at_xmax], 'Color','yellow', 'LineWidth',0.5);
                app.orthoLineHandles(end+1) = l;
            end
            % for i = 1:numel(app.orthoAll)
            %
            %     intercepts = app.orthoAll{i}(:, 2);
            %     slopes = app.orthoAll{i}(:, 1);
            %     for j = 1:numel(intercepts)
            %         y_at_xmin = slopes(j) * xmin + intercepts(j);
            %         y_at_xmax = slopes(j) * xmax + intercepts(j);
            %         l = plot(app.UIAxes2, [xmin xmax], [y_at_xmin y_at_xmax], 'Color', 'yellow', 'LineWidth',0.5);
            %         app.orthoLineHandles{end+1} = l;
            %     end
            % end
            hold (app.UIAxes2, 'off');
            app.UIAxes2.XLim = [xmin xmax];
            app.UIAxes2.YLim = [ylim(1) ylim(2)];

        end

        % Button pushed function: SelectgButton
        function SelectgButtonPushed(app, event)
            app.isSelecting = true;
            app.UIFigure.KeyPressFcn = @(src, eventdata) keyPressCallback(app, eventdata);
        end

        % Button pushed function: StopselectingButton
        function StopselectingButtonPushed(app, event)
            app.isSelecting = false;
            %TODO make the selected line go back to original color
            if (~isempty(app.selectedIdx))
                handle = app.orthoLineHandles{app.selectedIdx};
                set(handle, 'Color', 'yellow');
            end
        end

        % Button pushed function: ConfirmoutputButton
        function ConfirmoutputButtonPushed(app, event)
            [folder, ~, ~] = fileparts(app.img1Path);
            filepath = fullfile(folder, 'Orthogonal.mat');
            selected_orth = app.orthoAll(app.selectedIdx, :);
            result.slope = selected_orth(1);
            result.intercept = selected_orth(2);
            result.startPoint = [selected_orth(3) selected_orth(4)];
            result.border = app.clickedPts;
            save(filepath, "result");
            app.OutputstatusTextArea.Value = filepath;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Axon mask')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Image')
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.LoadrawButton = uibutton(app.UIFigure, 'push');
            app.LoadrawButton.ButtonPushedFcn = createCallbackFcn(app, @LoadrawButtonPushed, true);
            app.LoadrawButton.Text = 'Load raw';
            app.LoadskeletonButton = uibutton(app.UIFigure, 'push');
            app.LoadskeletonButton.ButtonPushedFcn = createCallbackFcn(app, @LoadskeletonButtonPushed, true);
            app.LoadskeletonButton.Text = 'Load skeleton';
            app.ImagepathTextAreaLabel = uilabel(app.UIFigure);
            app.ImagepathTextAreaLabel.Text = 'Image path';
            app.ImagepathTextArea = uitextarea(app.UIFigure);
            app.DeleteoutlineButton = uibutton(app.UIFigure, 'push');
            app.DeleteoutlineButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteoutlineButtonPushed, true);
            app.DeleteoutlineButton.Text = 'Delete outline';
            app.DrawoutlineButton = uibutton(app.UIFigure, 'push');
            app.DrawoutlineButton.ButtonPushedFcn = createCallbackFcn(app, @DrawoutlineButtonPushed, true);
            app.DrawoutlineButton.Text = 'Draw outline';
            app.SaveoutlineButton = uibutton(app.UIFigure, 'push');
            app.SaveoutlineButton.ButtonPushedFcn = createCallbackFcn(app, @SaveoutlineButtonPushed, true);
            app.SaveoutlineButton.Text = 'Save outline';
            app.DisplayonskeletonButton = uibutton(app.UIFigure, 'push');
            app.DisplayonskeletonButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayonskeletonButtonPushed, true);
            app.DisplayonskeletonButton.Text = 'Display on skeleton';
            app.ConfirmoutputButton = uibutton(app.UIFigure, 'push');
            app.ConfirmoutputButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmoutputButtonPushed, true);
            app.ConfirmoutputButton.Text = 'Confirm & output';
            app.OutputstatusTextAreaLabel = uilabel(app.UIFigure);
            app.OutputstatusTextAreaLabel.Text = 'Output status';
            app.OutputstatusTextArea = uitextarea(app.UIFigure);
            app.StopselectingButton = uibutton(app.UIFigure, 'push');
            app.StopselectingButton.ButtonPushedFcn = createCallbackFcn(app, @StopselectingButtonPushed, true);
            app.StopselectingButton.Text = 'Stop selecting';
            app.TotalorthogonalEditFieldLabel = uilabel(app.UIFigure);
            app.TotalorthogonalEditFieldLabel.Text = 'Total orthogonal';
            app.TotalorthogonalEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalorthogonalEditField.ValueChangedFcn = createCallbackFcn(app, @TotalorthogonalEditFieldValueChanged, true);
            app.SelectgButton = uibutton(app.UIFigure, 'push');
            app.SelectgButton.ButtonPushedFcn = createCallbackFcn(app, @SelectgButtonPushed, true);
            app.SelectgButton.Text = 'Select(g)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AxonSkewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end