classdef MatchReviewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        NextAxonMask              matlab.ui.control.Button
        TotalimagesTextArea       matlab.ui.control.TextArea
        TotalimagesTextAreaLabel  matlab.ui.control.Label
        LoadselectedAxonButton    matlab.ui.control.Button
        LoadSelectedRGCButton     matlab.ui.control.Button
        LoadRGCPixelColor         matlab.ui.control.Button
        LoadAxonPixelColor        matlab.ui.control.Button
        RefPic                    matlab.ui.control.Image
        MatchTable                matlab.ui.control.Table
        MatchListLabel            matlab.ui.control.Label
        RGCinfoLabel              matlab.ui.control.Label
        AxonInfoLabel             matlab.ui.control.Label
        RGCTable                  matlab.ui.control.Table
        AxonTable                 matlab.ui.control.Table
        StatusTextArea            matlab.ui.control.TextArea
        StatusTextAreaLabel       matlab.ui.control.Label
        LoaddataButton            matlab.ui.control.Button
        MouseidEditField          matlab.ui.control.NumericEditField
        MouseidEditFieldLabel     matlab.ui.control.Label
        RGCColorDist              matlab.ui.control.UIAxes
        AxonColorDist             matlab.ui.control.UIAxes
        ColorRepreFig             matlab.ui.control.UIAxes
        AxonDepth                 matlab.ui.control.UIAxes
        AxonZproj                 matlab.ui.control.UIAxes
        RGCDendriticStrat         matlab.ui.control.UIAxes
        RGConfaceFig              matlab.ui.control.UIAxes
        LGNTopoFig                matlab.ui.control.UIAxes
        SCTopoFig                 matlab.ui.control.UIAxes
        RetinaReconFig            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MouseId % animal djid of the mouse that is being processed
        AxonData=[] %basic information of this mouse
        RGCData=[] %basic information of this mouse
        matched_list = [] % list of matched axons and rgcs
        PlotHandles = struct( ...
            'SCtopo',         [], ...
            'RetinaRecon',     [], ...
            'dLGNtopo', [],...
            'ColorScatter', [],...
            'AxonImCDist', [],...
            'RGCImCDist', [],...
            'AxonZmax', [], ...
            'AxonDepth', [], ...
            'RGConFace',  [],...
            'RGCchat', []) % Handles for various plots
         SelectedAxonId = []; %in axon list, a row in the table can be selected by mouse
         SeletedCellUnid = []; %same but for axon
    end
    
    methods (Access = private)
        
        function axon_info = Load_axon_basic(app, animal_id)
            axon_info = [];
            qs = sprintf('animal_id = %d', animal_id);
            axon_raw_data = fetch(sln_cell.Axon & qs, '*');
            axon_raw_data = rmfield(axon_raw_data, 'animal_id');
            
            %tagging the associated image id to this
            for i = 1:numel(axon_raw_data)
                axon = axon_raw_data(i);
                qs = sprintf('axon_id = %d', axon.axon_id);
                imgids = fetch(sln_image.AxonImageAssociation & qs);
                ids = [imgids.image_id];
                axon.associated_ids = strjoin(string(ids), ', ');
                axon_info = [axon_info;axon];
            end
            
        end
        
        function rgc_info = load_rgc_basic(app, animal_id)
            qs = sprintf('animal_id = %d', animal_id);
            all_rgcs = fetch(sln_cell.Cell & qs);
            rgc_info = [];
            for i = 1:numel(all_rgcs)
                %fetching all typings
                rgc.cell_unid = all_rgcs(i).cell_unid;
                qs = sprintf('cell_unid = %d', all_rgcs(i).cell_unid);
                typings = fetch(sln_cell.CellEvent * sln_cell.AssignType & qs, '*');
                [~, idx] = max([typings.event_id]);
                rgc.cell_type = typings(idx).cell_class;
                rgc_info = [rgc_info; rgc];
            end

        end

        function results = fetchMatches(app, animal_id)
            qs = sprintf('animal_id = %d', animal_id);
            results = fetch(sln_cell.CellAxonMatch * sln_cell.Cell & qs);
        end

        function highlightMatched(app, tbl, ids, dataCol, color)
            idx = find(ismember(string(tbl.Data.(dataCol)), string(ids)));
            removeStyle(tbl);
            addStyle(tbl, uistyle('BackgroundColor', color), 'row', idx);
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.UIFigure.Position = [100 100 1440 1080];
            movegui(app.UIFigure, 'center');
        end

        % Button pushed function: LoaddataButton
        function LoaddataBP(app, event)
            %load the reference pic
            ref_picpath = 'reference.jpg';
            app.RefPic.ImageSource = ref_picpath;

            app.MouseId = app.MouseidEditField.Value;
            %query = sprintf('animal_id = %d', app.MouseId);
            app.StatusTextArea.Value = 'Loading mouse data...\n';
            try
            app.AxonData = app.Load_axon_basic(app.MouseId);
            app.RGCData = app.load_rgc_basic(app.MouseId);
            app.matched_list = app.fetchMatches(app.MouseId);
            catch ME
            %update
            app.StatusTextArea.Value = ME;
            end
            app.StatusTextArea.Value = sprintf('Loaded mouse %s: %d axons, %d RGCs!', app.MouseId, height(app.AxonData), height(app.RGCData));
            %update the table
            app.AxonTable.Data = struct2table(app.AxonData);
            app.RGCTable.Data = struct2table(app.RGCData);
            match_table = struct2table(app.matched_list);
            app.MatchTable.Data = match_table;

            %highlight both tables if matches returned
            if (~isempty(app.matched_list))
                app.highlightMatched(app.AxonTable, match_table.axon_id, 'axon_id', [0.85 0.95 0.85]);
                app.highlightMatched(app.RGCTable, match_table.cell_unid, 'cell_unid', [0.85 0.92 0.98]);
            end
            
            %plot retina reconstruction if exsits
            query = sprintf('animal_id = %d', app.MouseId);
            recondata = fetch(sln_tissue.RetinaRecon & query, '*');
            if (~isempty(recondata))
                app.PlotHandles.RetinaRecon = Plot_retinaRecon(app.RetinaReconFig, recondata);
                %app.PlotHandles.SC = Plot_SCtopo(app.SCAxes, contradata, ipData);
            else
                app.StatusTextArea.Value = [app.StatusTextArea.value;
                    'Warning: Cannot load retina reconstruction!\n'];
            end

            %plot SC topography if it exsits
            brainq = sprintf('brain_region = "SCs"');
            side = sprintf('side = "Contralateral"');
            SCcontradata = fetch(sln_cell.Axon * sln_cell.AxonCoordinate & query & brainq & side, '*');
            side = sprintf('side = "Ipsilateral"');
            SCipsdata = fetch(sln_cell.Axon * sln_cell.AxonCoordinate & query & brainq & side, '*');
            if (~isempty(SCcontradata))
                if(~isempty(SCipsdata))
                    app.PlotHandles.SCtopo = Plot_SCtopo(app.SCTopoFig, SCcontradata, SCipsdata);
                else
                    app.PlotHandles.SCtopo = Plot_SCtopo(app.SCTopoFig, SCcontradata);
                end
            else
                app.StatusTextArea.value = [app.StatusTextArea.Value;
                    'Warning: Cannot find any SC axon data!\n'];
            end
            
            %plot dLGN topography if it exsits-- skip for now

            %

        end

        % Cell selection callback: AxonTable
        function AxonTableCellSelection(app, event)
            if isempty(event.Indices)
                app.SelectedAxonId = [];
                return;
            end
            rowIdx = event.Indices(1,1);
            temparray = [app.AxonData.axon_id];
            app.SelectedAxonId = temparray(rowIdx);
        end

        % Button pushed function: LoadSelectedRGCButton
        function LoadSelectedRGCButtonPushed(app, event)
            morph_data = sln_image.RetinalCellMorphology & sprintf('cell_unid=%d',cell_unid);
            if morph_data.count == 1
                morph_data_struct = fetch(morph_data,'nodes_flattened','strat_x','strat_y_norm','lower_surface_z','upper_surface_z');
            else
                msgbox('No morphology data found for cell %d\n',cell_unid);
            end
            %plot enface trace with ouside functions
            

        end

        % Button pushed function: LoadselectedAxonButton
        function LoadselectedAxonButtonPushed(app, event)
            if (~isempty(app.SelectedAxonId))
                %display maximal projection
                query = sprintf('axon_id = %d', app.SelectedAxonId);
                maskdata = fetch(sln_image.AxonImageAssociation * sln_image.AxonInBrain & query, 'mask_image');
                maskstatus = sprintf('Found %d images of this axon', numel(maskdata));
                app.TotalimagesTextArea.Value = maskstatus;
                app.TotalimagesTextArea
                %NEED TO FIX: what if >= 2 images?
                if (numel(maskdata)>1)
                    axon_mask_zproj =  max(maskdata.mask_image(1), [], 3);
                else
                    axon_mask_zproj =  max(maskdata.mask_image, [], 3);
                end                
                app.PlotHandles.AxonZMax = Plot_Zproj(app.AxonZproj, axon_mask_zproj);

                %if axon have depth profile then display it as well
                axondepthdata = fetch(sln_cell.AxonImageMorph*sln_image.AxonImageAssociation & query, ...
                    'axon_density_along_depth');
                if (~isempty(axondepthdata))
                    app.PlotHandles.AxonDepth = plot_axon_depth(app.AxonDepth, axondepthdata);
                else
                    msgbox('No axon depth data found.\n');
                end
                
            else
                msgbox('Please select an axon first!');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RetinaReconFig = uiaxes(app.UIFigure);
            title(app.RetinaReconFig, 'Title')
            xlabel(app.RetinaReconFig, 'X')
            ylabel(app.RetinaReconFig, 'Y')
            zlabel(app.RetinaReconFig, 'Z')
            app.SCTopoFig = uiaxes(app.UIFigure);
            title(app.SCTopoFig, 'Title')
            xlabel(app.SCTopoFig, 'X')
            ylabel(app.SCTopoFig, 'Y')
            zlabel(app.SCTopoFig, 'Z')
            app.LGNTopoFig = uiaxes(app.UIFigure);
            title(app.LGNTopoFig, 'Title')
            xlabel(app.LGNTopoFig, 'X')
            ylabel(app.LGNTopoFig, 'Y')
            zlabel(app.LGNTopoFig, 'Z')
            app.RGConfaceFig = uiaxes(app.UIFigure);
            title(app.RGConfaceFig, 'Title')
            xlabel(app.RGConfaceFig, 'X')
            ylabel(app.RGConfaceFig, 'Y')
            zlabel(app.RGConfaceFig, 'Z')
            app.RGCDendriticStrat = uiaxes(app.UIFigure);
            title(app.RGCDendriticStrat, 'Title')
            xlabel(app.RGCDendriticStrat, 'X')
            ylabel(app.RGCDendriticStrat, 'Y')
            zlabel(app.RGCDendriticStrat, 'Z')
            app.AxonZproj = uiaxes(app.UIFigure);
            title(app.AxonZproj, 'Title')
            xlabel(app.AxonZproj, 'X')
            ylabel(app.AxonZproj, 'Y')
            zlabel(app.AxonZproj, 'Z')
            app.AxonDepth = uiaxes(app.UIFigure);
            title(app.AxonDepth, 'Title')
            xlabel(app.AxonDepth, 'X')
            ylabel(app.AxonDepth, 'Y')
            zlabel(app.AxonDepth, 'Z')
            app.ColorRepreFig = uiaxes(app.UIFigure);
            title(app.ColorRepreFig, 'Title')
            xlabel(app.ColorRepreFig, 'X')
            ylabel(app.ColorRepreFig, 'Y')
            zlabel(app.ColorRepreFig, 'Z')
            app.AxonColorDist = uiaxes(app.UIFigure);
            title(app.AxonColorDist, 'Title')
            xlabel(app.AxonColorDist, 'X')
            ylabel(app.AxonColorDist, 'Y')
            zlabel(app.AxonColorDist, 'Z')
            app.RGCColorDist = uiaxes(app.UIFigure);
            title(app.RGCColorDist, 'Title')
            xlabel(app.RGCColorDist, 'X')
            ylabel(app.RGCColorDist, 'Y')
            zlabel(app.RGCColorDist, 'Z')
            app.MouseidEditFieldLabel = uilabel(app.UIFigure);
            app.MouseidEditFieldLabel.Text = 'Mouse id';
            app.MouseidEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoaddataButton = uibutton(app.UIFigure, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataBP, true);
            app.LoaddataButton.Text = 'Load data';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.AxonTable = uitable(app.UIFigure);
            app.AxonTable.ColumnName = {'Axon id'; 'Brain region'; 'Side'; 'Asso imgs'};
            app.AxonTable.RowName = {};
            app.AxonTable.CellSelectionCallback = createCallbackFcn(app, @AxonTableCellSelection, true);
            app.RGCTable = uitable(app.UIFigure);
            app.RGCTable.ColumnName = {'Cell_unid'; 'Cell type'};
            app.RGCTable.RowName = {};
            app.AxonInfoLabel = uilabel(app.UIFigure);
            app.AxonInfoLabel.Text = 'Axon Info';
            app.RGCinfoLabel = uilabel(app.UIFigure);
            app.RGCinfoLabel.Text = 'RGC info';
            app.MatchListLabel = uilabel(app.UIFigure);
            app.MatchListLabel.Text = 'Match List';
            app.MatchTable = uitable(app.UIFigure);
            app.MatchTable.ColumnName = {'Cell_unid'; 'Axon_id'};
            app.MatchTable.RowName = {};
            app.RefPic = uiimage(app.UIFigure);
            app.LoadAxonPixelColor = uibutton(app.UIFigure, 'push');
            app.LoadAxonPixelColor.Text = 'Load Axon Pix Color';
            app.LoadRGCPixelColor = uibutton(app.UIFigure, 'push');
            app.LoadRGCPixelColor.Text = 'Load RGC Pix Color';
            app.LoadSelectedRGCButton = uibutton(app.UIFigure, 'push');
            app.LoadSelectedRGCButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSelectedRGCButtonPushed, true);
            app.LoadSelectedRGCButton.Text = 'Load Selected RGC';
            app.LoadselectedAxonButton = uibutton(app.UIFigure, 'push');
            app.LoadselectedAxonButton.ButtonPushedFcn = createCallbackFcn(app, @LoadselectedAxonButtonPushed, true);
            app.LoadselectedAxonButton.Text = 'Load selected Axon';
            app.TotalimagesTextAreaLabel = uilabel(app.UIFigure);
            app.TotalimagesTextAreaLabel.Text = 'Total images';
            app.TotalimagesTextArea = uitextarea(app.UIFigure);
            app.NextAxonMask = uibutton(app.UIFigure, 'push');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatchReviewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end